classdef a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        HITButton                  matlab.ui.control.Button
        STANDButton                matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        TextArea_5                 matlab.ui.control.TextArea
        TextArea_10                matlab.ui.control.TextArea
        TextArea_11                matlab.ui.control.TextArea
        Player1ScoreTextAreaLabel  matlab.ui.control.Label
        Player1ScoreTextArea       matlab.ui.control.TextArea
        Image                      matlab.ui.control.Image
        DealerScoreTextAreaLabel   matlab.ui.control.Label
        DealerScoreTextArea        matlab.ui.control.TextArea
        HelpButton                 matlab.ui.control.Button
        TotalValue1                matlab.ui.control.Label
        TotalValue2                matlab.ui.control.Label
        RetryButton                matlab.ui.control.Button
        ExitButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        card
        myCard
        myCard1
        dealerCard
        dealerCard1
        myScore
        dealerScore
    end
    
    methods (Access = private)
        function [cardNum,cardName] = getDeck(app,card) %assign cards' images and their respective value 
            if card==1
                cardName=('ace_of_hearts.png');
                cardNum=11;
            elseif card==2
                cardName=('2_of_hearts.png');
                cardNum=2;
            elseif card==3
                cardName=('3_of_hearts.png');
                cardNum=3;
            elseif card==4
                cardName=('4_of_hearts.png');
                cardNum=4;
            elseif card==5
                cardName=('5_of_hearts.png');
                cardNum=5;
            elseif card==6
                cardName=('6_of_hearts.png');
                cardNum=6;
            elseif card==7
                cardName=('7_of_hearts.png');
                cardNum=7;
            elseif card==8
                cardName=('8_of_hearts.png');
                cardNum=8;
            elseif card==9
                cardName=('9_of_hearts.png');
                cardNum=9;
            elseif card==10
                cardName=('10_of_hearts.png');
                cardNum=10;
            elseif card==11
                cardName=('jack_of_hearts.png');
                cardNum=10;
            elseif card==12
                cardName=('queen_of_hearts.png');
                cardNum=10;
            elseif card==13
                cardName=('king_of_hearts.png');
                cardNum=10;    
            elseif card==14
                cardName=('ace_of_clubs.png'); 
                cardNum=11;
            elseif card==15
                cardName=('2_of_clubs.png');
                cardNum=2;
            elseif card==16
                cardName=('3_of_clubs.png');
                cardNum=3;
            elseif card==17
                cardName=('4_of_clubs.png');
                cardNum=4;
            elseif card==18
                cardName=('5_of_clubs.png');
                cardNum=5;
            elseif card==19
                cardName=('6_of_clubs.png');
                cardNum=6;
            elseif card==20
                cardName=('7_of_clubs.png');
                cardNum=7;
            elseif card==21
                cardName=('8_of_clubs.png');
                cardNum=8;
            elseif card==22
                cardName=('9_of_clubs.png');
                cardNum=9;
            elseif card==23
                cardName=('10_of_clubs.png');
                cardNum=10;
            elseif card==24
                cardName=('jack_of_clubs.png');
                cardNum=10;
            elseif card==25
                cardName=('queen_of_clubs.png');
                cardNum=10;
            elseif card==26
                cardName=('king_of_clubs.png');
                cardNum=10;
            elseif card==27
                cardName=('ace_of_diamonds.png');
                cardNum=11;
            elseif card==28
                cardName=('2_of_diamonds.png');
                cardNum=2;
            elseif card==29
                cardName=('3_of_diamonds.png');
                cardNum=3;
            elseif card==30
                cardName=('4_of_diamonds.png');
                cardNum=4;
            elseif card==31
                cardName=('5_of_diamonds.png');
                cardNum=5;
            elseif card==32
                cardName=('6_of_diamonds.png');
                cardNum=6;
            elseif card==33
                cardName=('7_of_diamonds.png');
                cardNum=7;
            elseif card==34
                cardName=('8_of_diamonds.png');
                cardNum=8;
            elseif card==35
                cardName=('9_of_diamonds.png');
                cardNum=9;
            elseif card==36
                cardName=('10_of_diamonds.png');
                cardNum=10;
            elseif card==37
                cardName=('jack_of_diamonds.png');
                cardNum=10;
            elseif card==38
                cardName=('queen_of_diamonds.png');
                cardNum=10;
            elseif card==39
                cardName=('king_of_diamonds.png');
                cardNum=10;
            elseif card==40
                cardName=('ace_of_spades.png');
                cardNum=11;
            elseif card==41
                cardName=('2_of_spades.png');
                cardNum=2;
            elseif card==42
                cardName=('3_of_spades.png');
                cardNum=3;
            elseif card==43
                cardName=('4_of_spades.png');
                cardNum=4;
            elseif card==44
                cardName=('5_of_spades.png');
                cardNum=5;
            elseif card==45
                cardName=('6_of_spades.png');
                cardNum=6;
            elseif card==46
                cardName=('7_of_spades.png');
                cardNum=7;
            elseif card==47
                cardName=('8_of_spades.png');
                cardNum=8;
            elseif card==48
                cardName=('9_of_spades.png');
                cardNum=9;
            elseif card==49
                cardName=('10_of_spades.png');
                cardNum=10;
            elseif card==50
                cardName=('jack_of_spades.png');
                cardNum=10;
            elseif card==51
                cardName=('queen_of_spades.png');
                cardNum=10;
            elseif card==52
                cardName=('king_of_spades.png');
                cardNum=10;
            end
        end
        
        function [] = score(app) % instead of a bidding system, our game uses a scoring system to keep track of the progress.
            %part 1: this part of the code displays the result of the match
            if app.dealerCard.Value==21 & ~(app.myCard.Value==21)
                app.TextArea_11.Visible = 'on';
                app.TextArea_11.Value=("Dealer's 21/Blackjack! -10 points");
            elseif app.myCard.Value==21 & ~(app.dealerCard.Value==21)
                app.TextArea_11.Visible = 'on';
                app.TextArea_11.Value=("Player's 21/Blackjack! +10 points ");
            elseif app.dealerCard.Value>21
                app.TextArea_11.Visible = 'on';
                app.TextArea_11.Value=("Dealer Bursts! +5 point");
            elseif app.dealerCard.Value<21
                app.TextArea_11.Visible = 'on';
                app.TextArea_11.Value=("Dealer Stands");
                pause(1);
                if app.dealerCard.Value==app.myCard.Value
                    app.TextArea_11.Visible = 'on';
                    app.TextArea_11.Value=("Push! +0 points");
                elseif app.dealerCard.Value<app.myCard.Value
                    app.TextArea_11.Visible = 'on';
                    app.TextArea_11.Value=("Player Wins! +5 points");
                elseif app.dealerCard.Value>app.myCard.Value
                    app.TextArea_11.Visible = 'on';
                    app.TextArea_11.Value=("Dealer Wins! -5 points");
                end
            elseif app.dealerCard.Value==app.myCard.Value
                app.TextArea_11.Visible = 'on'; 
                app.TextArea_11.Value=("Push!");
            end
            %part2: this part of the code focusing on the score system,
            %which ultimately decide whether the players wins/loses the
            %match 
            if app.myCard.Value>app.dealerCard.Value & app.myCard.Value <=20
                app.myScore.Value=app.myScore.Value+5;
                app.dealerScore.Value=app.dealerScore.Value-5;
                app.Player1ScoreTextArea.Value=string(app.myScore.Value);
                app.DealerScoreTextArea.Value=string(app.dealerScore.Value);
            elseif app.myCard.Value==21 & ~(app.dealerCard.Value==21)
                app.myScore.Value=app.myScore.Value+10;
                app.Player1ScoreTextArea.Value=string(app.myScore.Value);
                app.dealerScore.Value=app.dealerScore.Value-10;
                app.DealerScoreTextArea.Value=string(app.dealerScore.Value);
            elseif app.dealerCard.Value==21 & ~(app.myCard.Value==21)
                app.myScore.Value=app.myScore.Value-10;
                app.Player1ScoreTextArea.Value=string(app.myScore.Value);
                app.dealerScore.Value=app.dealerScore.Value+10;
                app.DealerScoreTextArea.Value=string(app.dealerScore.Value);
            elseif app.myCard.Value==app.dealerCard.Value
                app.myScore.Value=app.myScore.Value;
                app.Player1ScoreTextArea.Value=string(app.myScore.Value);
                app.dealerScore.Value=app.dealerScore.Value;
                app.DealerScoreTextArea.Value=string(app.dealerScore.Value);
            elseif app.dealerCard.Value>app.myCard.Value & app.dealerCard.Value<=20
                app.myScore.Value=app.myScore.Value-5;
                app.Player1ScoreTextArea.Value=string(app.myScore.Value);
                app.dealerScore.Value=app.dealerScore.Value+5;
                app.DealerScoreTextArea.Value=string(app.dealerScore.Value);
            elseif app.dealerCard.Value>21
                app.myScore.Value=app.myScore.Value+5;
                app.Player1ScoreTextArea.Value=string(app.myScore.Value);
                app.dealerScore.Value=app.dealerScore.Value-5;
                app.DealerScoreTextArea.Value=string(app.dealerScore.Value);
            end
        end
        % deal 4 cards: 2 for the players and 2 for the dealers
        function [] = deal4Card(app)
            card1=sort(randperm(52,1));
            [card1num,card1name]=getDeck(app,card1);
            card2=sort(randperm(52,1));
            [card2num,card2name]=getDeck(app,card2);
            app.Image = uiimage(app.UIFigure);
            app.Image.Position = [31 25 100 100];
            app.Image.ImageSource = card1name;
            app.Image = uiimage(app.UIFigure);
            app.Image.Position = [31 25 250 100];
            app.Image.ImageSource = card2name;
            card3=sort(randperm(52,1));
            [card3num,card3name]=getDeck(app,card3);
            card4=sort(randperm(52,1));
            [card4num,card4name]=getDeck(app,card4);
            app.card.Value=card4name;
            app.Image = uiimage(app.UIFigure);
            app.Image.Position = [31 345 100 100];
            app.Image.ImageSource = card3name;
            app.Image = uiimage(app.UIFigure);
            app.Image.Position = [31 343 250 102];
            app.Image.ImageSource = 'back_of_card.png';
            card3num = dealerAce(app,card3num);
            card4num = dealerAce(app,card4num);
            app.dealerCard.Value=card3num+card4num;
            card1num=ace(app,card1num);
            card2num=ace(app,card2num);
            app.myCard.Value=card1num+card2num;
            app.TextArea_5.Value=string(app.myCard.Value);
            
        end
        
        function cardNum = ace(app,cardNum)
            %allow players to pick ace's value
            %once decide, the ace'value in player's hand will no longer be
            %change unless the match ends.
            if cardNum==11
                choice = menu('Do you want value of Ace to be 1?',...
                    'Yes','No');
                if choice==1
                    cardNum=1;
                else
                    cardNum=11;
                end
            end
        end
        
        function cardNum = dealerAce(app,cardNum)
            %unlike playes', the AI dealer will automatically decide its
            %ace value base on the current dealer count. Once it decided,
            %the program can no longer change the value.
            if cardNum==11;
                if isempty(app.dealerCard) | app.dealerCard.Value<=10
                    cardNum=11;
                else
                    cardNum=1;
                end
            end
        end
        
        function [] = reset(app)
            
            clear startupFcn;
            clear deal4Card; 
            clear HitCard;
            clear STANDButtonPushed;
            clear StartButtonPushed;
            StartButtonPushed(app)
        end
        
        function [cardnum1,cardname1] = HitCard(app)
            card1=sort(randperm(52,1));
            [cardnum1,cardname1]=getDeck(app,card1);
            cardnum1=ace(app,cardnum1);
            i=1;
            while i<5 
                if i==1
                    app.Image = uiimage(app.UIFigure);
                    app.Image.Position = [31 25 400 100];
                    app.Image.ImageSource = cardname1;
                end
                i=i+1;
            end
        end
        
        function [] = getWin(app)
            if app.myScore.Value<=0
            app.TextArea_11.Value=("Player lost the game!");
            pause(1);
            close(app);
            elseif app.dealerScore.Value<=0
            app.TextArea_11.Value=("Player wins the game!");
            pause(1);
            close(app);
            end
        end
        
        function []=close(app)
            set(app.ExitButton, 'Enable', 'on');
            app.ExitButton.Visible = 'on';
            set(app.RetryButton, 'Enable', 'on');
            app.RetryButton.Visible = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % [d,s] = audioread('CantinaBand.mp3');
            %sound(d,s);
            app.TotalValue1.Visible='off';
            app.TotalValue2.Visible='off';
            app.Image.Visible='off';
            app.Image.Visible='off';
            app.myCard.Value=0;
            set(app.HITButton, 'Enable', 'off');
            app.HITButton.Visible = 'off';
            set(app.ExitButton, 'Enable', 'off');
            app.ExitButton.Visible = 'off';
            set(app.RetryButton, 'Enable', 'off');
            app.RetryButton.Visible = 'off';
            set(app.STANDButton, 'Enable', 'off');
            app.STANDButton.Visible = 'off';
            set(app.TextArea_5, 'Enable', 'off');
            app.TextArea_5.Visible = 'off';
            app.TextArea_10.Visible = 'off';
            app.TextArea_11.Visible = 'off';
            app.Player1ScoreTextArea.Visible ='off';
            app.Player1ScoreTextAreaLabel.Visible ='off';
            app.DealerScoreTextArea.Visible ='off';
            app.DealerScoreTextAreaLabel.Visible ='off';
            app.myScore.Value=10;
            app.dealerScore.Value=10;
            app.DealerScoreTextArea.Value=string(app.dealerScore.Value);
            app.Player1ScoreTextArea.Value=string(app.myScore.Value);
            
        end

        % Button pushed function: HITButton
        function HITButtonPushed(app, event)
            %player can ask for new card until they are satisfied with
            %their value or until they go over 21
            [cardnum1,cardname1] = HitCard(app);
            app.myCard.Value=app.myCard.Value+cardnum1;
            app.TextArea_5.Value=string(app.myCard.Value);
            if app.myCard.Value >21
                app.myScore.Value=app.myScore.Value-10;
                app.Player1ScoreTextArea.Value=string(app.myScore.Value);
                app.dealerScore.Value=app.dealerScore.Value+10;
                app.DealerScoreTextArea.Value=string(app.dealerScore.Value);
                app.TextArea_11.Visible = 'on';
                app.TextArea_11.Value=("Dealer Wins! -10 points");
                set(app.STANDButton, 'Enable', 'off');
                set(app.HITButton, 'Enable', 'off');
                app.Image = uiimage(app.UIFigure);
                app.Image.Position = [23 343 250 97];
                app.Image.ImageSource = 'back_of_card.png';
                delete(app.Image);
                app.Image = uiimage(app.UIFigure);
                app.Image.Position = [31 345 250 100];
                app.Image.ImageSource = app.card.Value;
                pause(2);
                app.Image = uiimage(app.UIFigure);
                app.Image.Position = [31 25 400 100];
                app.Image.ImageSource = 'GreenBackground.png';
                app.Image = uiimage(app.UIFigure);
                app.Image.Position = [31 345 400 100];
                app.Image.ImageSource = 'GreenBackground.png';
                if app.myScore.Value<=0 || app.dealerScore.Value<=0
                    getWin(app);
                else
                    reset(app);
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            deal4Card(app);
            app.TotalValue1.Visible='on';
            app.TotalValue2.Visible='on';
            set(app.HITButton, 'Enable', 'on');
            app.HITButton.Visible = 'on';
            set(app.STANDButton, 'Enable', 'on');
            app.STANDButton.Visible = 'on';
            set(app.TextArea_5, 'Enable', 'on');
            app.TextArea_5.Visible = 'on';
            set(app.StartButton, 'Enable', 'off');
            app.StartButton.Visible= 'off';
            app.TextArea_10.Visible = 'off';
            app.TextArea_11.Visible = 'off';
            app.Player1ScoreTextArea.Visible ='on';
            app.Player1ScoreTextAreaLabel.Visible ='on';
            app.DealerScoreTextArea.Visible ='on';
            app.DealerScoreTextAreaLabel.Visible ='on';
        end

        % Button pushed function: STANDButton
        function STANDButtonPushed(app, event)
            %players can use this button to stop their turns. This allow
            %the AI dealer to hit and stand based on the its cards. 
            set(app.HITButton, 'Enable', 'off');
            set(app.STANDButton, 'Enable', 'off');
            app.Image = uiimage(app.UIFigure);
            app.Image.Position = [31 345 250 100];
            app.Image.ImageSource = app.card.Value;
            while app.dealerCard.Value<=16
                app.TextArea_10.Visible='off';
                card2=sort(randperm(52,1));
                [cardnum,cardname]=getDeck(app,card2);
                app.Image = uiimage(app.UIFigure);
                app.Image.Position = [23 343 250 97];
                app.Image.ImageSource = 'back_of_card.png';
                delete(app.Image)
                pause(1)
                app.TextArea_10.Visible = 'on';
                app.Image = uiimage(app.UIFigure);
                app.Image.Position = [31 345 400 100];
                app.Image.ImageSource = cardname;
                cardnum = dealerAce(app,cardnum);
                app.dealerCard.Value=app.dealerCard.Value+cardnum;
                app.TextArea_10.Value=string(app.dealerCard.Value);
            end
            if app.dealerCard.Value>16
                app.TextArea_10.Visible = 'on';
                app.TextArea_10.Value=string(app.dealerCard.Value);
            end
            score(app);
            pause(2);
            app.Image = uiimage(app.UIFigure);
            app.Image.Position = [31 25 400 100];
            app.Image.ImageSource = 'GreenBackground.png';
            app.Image = uiimage(app.UIFigure);
            app.Image.Position = [31 345 400 100];
            app.Image.ImageSource = 'GreenBackground.png';
            if app.myScore.Value<=0 || app.dealerScore.Value<=0
                getWin(app);
            else
                reset(app);
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed2(app, event)
            Help
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            choice = menu('Are you sure?',...
                    'Yes','No');
                if choice==1
                    close all force
                else
                    close(app);
                end
        end

        % Button pushed function: RetryButton
        function RetryButtonPushed(app, event)
            reset(app);
            app.myScore.Value=40;
            app.dealerScore.Value=40;
            app.DealerScoreTextArea.Value=string(app.dealerScore.Value);
            app.Player1ScoreTextArea.Value=string(app.myScore.Value);
            set(app.ExitButton, 'Enable', 'off');
            app.ExitButton.Visible = 'off';
            set(app.RetryButton, 'Enable', 'off');
            app.RetryButton.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HITButton = uibutton(app.UIFigure, 'push');
            app.HITButton.ButtonPushedFcn = createCallbackFcn(app, @HITButtonPushed, true);
            app.HITButton.Text = {'HIT'; ''};
            app.STANDButton = uibutton(app.UIFigure, 'push');
            app.STANDButton.ButtonPushedFcn = createCallbackFcn(app, @STANDButtonPushed, true);
            app.STANDButton.Text = 'STAND';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Editable = 'off';
            app.TextArea_10 = uitextarea(app.UIFigure);
            app.TextArea_10.Editable = 'off';
            app.TextArea_11 = uitextarea(app.UIFigure);
            app.TextArea_11.Editable = 'off';
            app.TextArea_11.Visible = 'off';
            app.Player1ScoreTextAreaLabel = uilabel(app.UIFigure);
            app.Player1ScoreTextAreaLabel.Text = 'Player 1 Score';
            app.Player1ScoreTextArea = uitextarea(app.UIFigure);
            app.Player1ScoreTextArea.Editable = 'off';
            app.Image = uiimage(app.UIFigure);
            app.DealerScoreTextAreaLabel = uilabel(app.UIFigure);
            app.DealerScoreTextAreaLabel.Text = 'Dealer Score';
            app.DealerScoreTextArea = uitextarea(app.UIFigure);
            app.DealerScoreTextArea.Editable = 'off';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed2, true);
            app.HelpButton.Text = 'Help';
            app.TotalValue1 = uilabel(app.UIFigure);
            app.TotalValue1.Text = 'Total Value';
            app.TotalValue2 = uilabel(app.UIFigure);
            app.TotalValue2.Text = 'Total Value';
            app.RetryButton = uibutton(app.UIFigure, 'push');
            app.RetryButton.ButtonPushedFcn = createCallbackFcn(app, @RetryButtonPushed, true);
            app.RetryButton.Text = 'Retry';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end